classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UruchomieniesilnikaSwitchLabel  matlab.ui.control.Label
        UruchomieniesilnikaSwitch       matlab.ui.control.Switch
        KierunekobrotwButtonGroup       matlab.ui.container.ButtonGroup
        ZgodniezruchemzegaraButton      matlab.ui.control.RadioButton
        PrzeciwniedoruchuzegaraButton   matlab.ui.control.RadioButton
        RozpocznijsymulacjButton        matlab.ui.control.Button
        SterowanieButtonGroup           matlab.ui.container.ButtonGroup
        TorotwartyButton                matlab.ui.control.RadioButton
        TorzamknityButton               matlab.ui.control.RadioButton
        TrybmanualnyButton              matlab.ui.control.RadioButton
        PrdkoobrotowaobrsSliderLabel    matlab.ui.control.Label
        PrdkoobrotowaobrsSlider         matlab.ui.control.Slider
        KompilacjakoduButton            matlab.ui.control.Button
        ZakoczsymulacjButton            matlab.ui.control.Button
        PoczzurzdzeniemButton           matlab.ui.control.Button
        KtobrotuSpinnerLabel            matlab.ui.control.Label
        KtobrotuSpinner                 matlab.ui.control.Spinner
        AplikacjadosterowaniasilnikiemkrokowymLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        
        start = 1; % Description
        kierunek = 0;
        Speed = 0;

    end

    properties (Access = public)
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        set_param('Start/Uruchomienie_silnika','gain','1');
        set_param('Start/Predkosc/Predkosc_obrotowa','gain','0');
        assignin('base', 'kat', 0);
        end

        % Value changed function: UruchomieniesilnikaSwitch
        function UruchomieniesilnikaSwitchValueChanged(app, event)
            value = app.UruchomieniesilnikaSwitch.Value;
            if strcmp(value, 'Off')
                set_param('Start/Uruchomienie_silnika','gain','1');
            else
                set_param('Start/Uruchomienie_silnika','gain','0');
            end
        end

        % Button pushed function: RozpocznijsymulacjButton
        function RozpocznijsymulacjButtonPushed(app, event)
            %                       simOut = sim('Start.slx','StopTime','30');
                      set_param('Start', 'SimulationCommand', 'start')
        end

        % Selection changed function: KierunekobrotwButtonGroup
        function KierunekobrotwButtonGroupSelectionChanged(app, event)
            Kier = app.KierunekobrotwButtonGroup.SelectedObject;
            
            
                 if Kier == app.ZgodniezruchemzegaraButton
                 set_param('Start/Zmiana_kierunku','gain','1');
                 else
                 set_param('Start/Zmiana_kierunku','gain','0');
                 end
            
          
        end

        % Value changed function: PrdkoobrotowaobrsSlider
        function PrdkoobrotowaobrsSliderValueChanged(app, event)
            wartosc = app.PrdkoobrotowaobrsSlider.Value;
           
            handle = getSimulinkBlockHandle('Start/Predkosc/Predkosc_obrotowa');
            if wartosc < 1
            set_param(handle,'gain','0');
            end
            if wartosc < 2 & wartosc > 1
            set_param(handle,'gain','1');
            end
            if wartosc < 3 & wartosc > 2
            set_param(handle,'gain','2');
            end
            if wartosc < 4 & wartosc > 3
            set_param(handle,'gain','3');
            end
            if wartosc < 5 & wartosc > 4
            set_param(handle,'gain','4');
            end
            if wartosc < 6 & wartosc > 5
            set_param(handle,'gain','5');
            end
            if wartosc < 7 & wartosc > 6
            set_param(handle,'gain','6');
            end
            if wartosc < 8 & wartosc > 7
            set_param(handle,'gain','7');
            end
            if wartosc < 9 & wartosc > 8
            set_param(handle,'gain','8');
            end
            if wartosc < 10 & wartosc > 9
            set_param(handle,'gain','9');
            end
            if wartosc == 10
            set_param(handle,'gain','10');
            end
        end

        % Selection changed function: SterowanieButtonGroup
        function SterowanieButtonGroupSelectionChanged(app, event)
            Ster = app.SterowanieButtonGroup.SelectedObject;
            if Ster == app.TorotwartyButton
            set_param('Start/Predkosc/Tryb_manualny','value','0');
            set_param('Start/Zmiana_trybu_pracy','value','1');
            elseif Ster == app.TorzamknityButton
            set_param('Start/Zmiana_trybu_pracy','value','0');
            set_param('Start/Predkosc/Tryb_manualny','value','0');
            else
            set_param('Start/Predkosc/Tryb_manualny','value','1');
            set_param('Start/Zmiana_trybu_pracy','value','1');
            end
        end

        % Button pushed function: ZakoczsymulacjButton
        function ZakoczsymulacjButtonPushed(app, event)
            set_param('Start', 'SimulationCommand', 'stop');
        end

        % Button pushed function: PoczzurzdzeniemButton
        function PoczzurzdzeniemButtonPushed(app, event)
            set_param('Start', 'SimulationCommand', 'connect');
        end

        % Button pushed function: KompilacjakoduButton
        function KompilacjakoduButtonPushed(app, event)
              slbuild('Start');
        end

        % Value changed function: KtobrotuSpinner
        function KtobrotuSpinnerValueChanged(app, event)
            value = app.KtobrotuSpinner.Value;
            
            assignin('base', 'kat', value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UruchomieniesilnikaSwitchLabel = uilabel(app.UIFigure);
            app.UruchomieniesilnikaSwitchLabel.Text = 'Uruchomienie silnika';
            app.UruchomieniesilnikaSwitch = uiswitch(app.UIFigure, 'slider');
            app.UruchomieniesilnikaSwitch.ValueChangedFcn = createCallbackFcn(app, @UruchomieniesilnikaSwitchValueChanged, true);
            app.KierunekobrotwButtonGroup = uibuttongroup(app.UIFigure);
            app.KierunekobrotwButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KierunekobrotwButtonGroupSelectionChanged, true);
            app.KierunekobrotwButtonGroup.Title = 'Kierunek obrotów';
            app.ZgodniezruchemzegaraButton = uiradiobutton(app.KierunekobrotwButtonGroup);
            app.ZgodniezruchemzegaraButton.Text = 'Zgodnie z ruchem zegara';
            app.ZgodniezruchemzegaraButton.Value = true;
            app.PrzeciwniedoruchuzegaraButton = uiradiobutton(app.KierunekobrotwButtonGroup);
            app.PrzeciwniedoruchuzegaraButton.Text = 'Przeciwnie do ruchu zegara';
            app.RozpocznijsymulacjButton = uibutton(app.UIFigure, 'push');
            app.RozpocznijsymulacjButton.ButtonPushedFcn = createCallbackFcn(app, @RozpocznijsymulacjButtonPushed, true);
            app.RozpocznijsymulacjButton.Text = 'Rozpocznij symulację';
            app.SterowanieButtonGroup = uibuttongroup(app.UIFigure);
            app.SterowanieButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SterowanieButtonGroupSelectionChanged, true);
            app.SterowanieButtonGroup.Title = 'Sterowanie';
            app.TorotwartyButton = uiradiobutton(app.SterowanieButtonGroup);
            app.TorotwartyButton.Text = 'Tor otwarty';
            app.TorotwartyButton.Value = true;
            app.TorzamknityButton = uiradiobutton(app.SterowanieButtonGroup);
            app.TorzamknityButton.Text = 'Tor zamknięty';
            app.TrybmanualnyButton = uiradiobutton(app.SterowanieButtonGroup);
            app.TrybmanualnyButton.Text = 'Tryb manualny';
            app.PrdkoobrotowaobrsSliderLabel = uilabel(app.UIFigure);
            app.PrdkoobrotowaobrsSliderLabel.Text = 'Prędkość obrotowa [obr/s]';
            app.PrdkoobrotowaobrsSlider = uislider(app.UIFigure);
            app.PrdkoobrotowaobrsSlider.Limits = [0 10];
            app.PrdkoobrotowaobrsSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.PrdkoobrotowaobrsSlider.ValueChangedFcn = createCallbackFcn(app, @PrdkoobrotowaobrsSliderValueChanged, true);
            app.KompilacjakoduButton = uibutton(app.UIFigure, 'push');
            app.KompilacjakoduButton.ButtonPushedFcn = createCallbackFcn(app, @KompilacjakoduButtonPushed, true);
            app.KompilacjakoduButton.Text = 'Kompilacja kodu';
            app.ZakoczsymulacjButton = uibutton(app.UIFigure, 'push');
            app.ZakoczsymulacjButton.ButtonPushedFcn = createCallbackFcn(app, @ZakoczsymulacjButtonPushed, true);
            app.ZakoczsymulacjButton.Text = 'Zakończ symulację';
            app.PoczzurzdzeniemButton = uibutton(app.UIFigure, 'push');
            app.PoczzurzdzeniemButton.ButtonPushedFcn = createCallbackFcn(app, @PoczzurzdzeniemButtonPushed, true);
            app.PoczzurzdzeniemButton.Text = 'Połącz z urządzeniem';
            app.KtobrotuSpinnerLabel = uilabel(app.UIFigure);
            app.KtobrotuSpinnerLabel.Text = 'Kąt obrotu';
            app.KtobrotuSpinner = uispinner(app.UIFigure);
            app.KtobrotuSpinner.Step = 5;
            app.KtobrotuSpinner.ValueChangedFcn = createCallbackFcn(app, @KtobrotuSpinnerValueChanged, true);
            app.AplikacjadosterowaniasilnikiemkrokowymLabel = uilabel(app.UIFigure);
            app.AplikacjadosterowaniasilnikiemkrokowymLabel.Text = 'Aplikacja do sterowania silnikiem krokowym';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end